classdef test_epilepsiae < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OptionsLabel             matlab.ui.control.Label
        ClassbalancingCheckBox   matlab.ui.control.CheckBox
        ErrorWeightsCheckBox     matlab.ui.control.CheckBox
        OutputLabel              matlab.ui.control.Label
        OutputTextArea           matlab.ui.control.TextArea
        ButtonGroup              matlab.ui.container.ButtonGroup
        TestButton_2             matlab.ui.control.RadioButton
        TrainandtestButton       matlab.ui.control.RadioButton
        TestButton               matlab.ui.control.Button
        ArchitectureButtonGroup  matlab.ui.container.ButtonGroup
        ShallowNNButton          matlab.ui.control.RadioButton
        RecurrentNNButton        matlab.ui.control.RadioButton
        LSTMButton               matlab.ui.control.RadioButton
        CNNButton                matlab.ui.control.RadioButton
        DatasetsmustbeinsidedatafolderLabel  matlab.ui.control.Label
        FilenameEditField        matlab.ui.control.EditField
        FilenameEditFieldLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)


            if app.ShallowNNButton.Value == true
                NN_name= "SNN";
            elseif app.RecurrentNNButton.Value == true
                NN_name= "RNN";
            elseif app.LSTMButton.Value == true
                NN_name= "LSTM";
            elseif app.CNNButton.Value == true
                NN_name= "CNN";
            end

            my_test(NN_name, app.ErrorWeightsCheckBox.Value, app.ClassbalancingCheckBox.Value , app.FilenameEditField.Value, app.TrainandtestButton.Value, app.OutputTextArea);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.DatasetsmustbeinsidedatafolderLabel = uilabel(app.UIFigure);
            app.DatasetsmustbeinsidedatafolderLabel.Text = 'Datasets must be inside data folder';
            app.ArchitectureButtonGroup = uibuttongroup(app.UIFigure);
            app.ArchitectureButtonGroup.Title = 'Architecture';
            app.CNNButton = uiradiobutton(app.ArchitectureButtonGroup);
            app.CNNButton.Text = 'CNN';
            app.CNNButton.Value = true;
            app.LSTMButton = uiradiobutton(app.ArchitectureButtonGroup);
            app.LSTMButton.Text = 'LSTM';
            app.RecurrentNNButton = uiradiobutton(app.ArchitectureButtonGroup);
            app.RecurrentNNButton.Text = 'Recurrent NN';
            app.ShallowNNButton = uiradiobutton(app.ArchitectureButtonGroup);
            app.ShallowNNButton.Text = 'Shallow NN';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.TrainandtestButton = uiradiobutton(app.ButtonGroup);
            app.TrainandtestButton.Text = 'Train and test';
            app.TrainandtestButton.Value = true;
            app.TestButton_2 = uiradiobutton(app.ButtonGroup);
            app.TestButton_2.Text = 'Test';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
            app.ErrorWeightsCheckBox = uicheckbox(app.UIFigure);
            app.ErrorWeightsCheckBox.Text = 'Error Weights';
            app.ClassbalancingCheckBox = uicheckbox(app.UIFigure);
            app.ClassbalancingCheckBox.Text = 'Class balancing';
            app.OptionsLabel = uilabel(app.UIFigure);
            app.OptionsLabel.Text = 'Options:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_epilepsiae

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end